classdef ArtisticStyleTransfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ArtisticStyleTransferLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        StyleDropDown               matlab.ui.control.DropDown
        Image                       matlab.ui.control.Image
        StyleImageNoStyleLabel      matlab.ui.control.Label
        TakePictureButton           matlab.ui.control.Button
        ButtonLeft                  matlab.ui.control.Button
        ButtonRight                 matlab.ui.control.Button
        ResolutionSwitch            matlab.ui.control.Switch
        ResolutionLabel             matlab.ui.control.Label
        ProcessingUnit              matlab.ui.control.Switch
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

%  Copyright 2020 The MathWorks, Inc.

    properties (Access = private)
        
        Camera % Description
        WebcamResizeFactor = 1/2 % Resize factor for resolution
        Networks % Pretrained Networks
        CurrentImage % Current image
        LoadBar % Loading bar
        DropDownMap  % Map from drop down values to numeric indices
        CurrentDropDownIndex = 1 % Current value of associated dropdown index
          
    end
    
    methods (Access = private)
        
        function GenerateVideo(app)
            

            while isvalid(app)
              
                imWebcam = single(snapshot(app.Camera));
                im = imresize(imWebcam,app.WebcamResizeFactor);
                
                dlX = dlarray(im,'SSC');
                
                if app.ProcessingUnit.Value == "GPU" && canUseGPU
                    dlX = gpuArray(dlX);
                end
                
                if app.StyleDropDown.Value == "No Style"
                    app.CurrentImage = imshow(uint8(im),'Parent',app.UIAxes,'InitialMagnification','fit');
                else
                    
                    %dlY = predict(app.Networks.(string(app.StyleDropDown.Value)),dlX);
                    dlY = app.Networks.(string(app.StyleDropDown.Value)).predict(dlX);
                    Y = (tanh(dlY)+1)/2;
                    Y = extractdata(Y);
                    app.CurrentImage = imshow(Y,'Parent',app.UIAxes,'InitialMagnification','fit');
                end
                
                % Refresh properties
                drawnow
            end
                
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Verify if user has necessary toolboxes
            allInstalled = checkRequiredInstallation(app);
            
            if ~allInstalled
                delete(app)
                return
            end
            
            % Add try block if app errors during startup
            try
                
               
                % Maximize Window
                drawnow
                app.UIFigure.WindowState = "maximized";
                
                % Create loading bar
                app.LoadBar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Starting the app');
                
                % Show empty image for style image
                app.Image.ImageSource = "";
                
                % Store webcam object in app.Camera
                app.Camera = webcam;
                
                % Check if a GPU is available for use
                if ~canUseGPU
                    app.ProcessingUnit.Value = "CPU";
                    app.ProcessingUnit.Enable = 0;
                end 
                
                % Load all the networks from the networks directory
                path = 'networks';
                contents = what(path);
                filenames = contents.mat;

                %Specify No Style to be the default dropdown vlaue
                app.StyleDropDown.Items = {'No Style'};
                app.StyleDropDown.Value = 'No Style';
                
                % Increase load bar progress
                app.LoadBar.Value = 0.1;
                
                % Load all the files in a loop
                for i = 1:numel(filenames)
                    
                    app.LoadBar.Message = ['Loading ', num2str(filenames{i})];
                    
                    try
                        
                        % Load dlnetwork
                        pretrainedNetwork = load(fullfile(path,filenames{i}));
                        
                        % Remove .mat extension
                        filename_no_extension = filenames{i}(1:end-4);
                        
                        % Store dlnetwork variable in app.Networks
                        app.Networks.(filename_no_extension) = pretrainedNetwork.dlnetTransform;
                        
                        
                        % Test network
                        randImg = dlarray(rand([2 2 3]),'SSC');
                        forward(app.Networks.(filename_no_extension),randImg);
                        
                        % Add the loaded network to the list of dropdown items
                        app.StyleDropDown.Items{end+1} = filename_no_extension;
                        
                    catch e
                        message = ['Something went wrong when trying to load ',num2str(filenames{i}),...
                            newline, e.message];
                        msgbox(message,'Warning','warn');
                    end
                    
                    % Update the loading progress
                    app.LoadBar.Value = 0.1 + 0.9*i/numel(filenames);
                    
                    % Refresh properties
                    drawnow
                    
                end
                
                
                % Create map to associate drop down values to indices
                keySet = app.StyleDropDown.Items;
                valueSet = 1: length(app.StyleDropDown.Items);
                app.DropDownMap = containers.Map(keySet,valueSet);
                
                close(app.LoadBar)
                
            catch e
                % Handle case when something errors during startup
                if isvalid(app)
                    delete(app)
                end
                message = ['Something went wrong when starting the app.' newline e.message];
                msgbox(message,'Error','error');
                return
            end
            
            % Call the function which generates video
            app.GenerateVideo();
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            delete(app)
            disp('app closed')
            
        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
           
            % Create pictures folder
            if ~exist('pictures','dir')
                mkdir('pictures')
            end
            
            % Save pictures to pictures folder
            imageFileName = ['image', datestr(now,'dd-mmm-yyyy-HH-MM-SS'), '.jpg'];
            fullDestination = fullfile('pictures',imageFileName);
            % Mirror the image
            imgMirrored = fliplr(app.CurrentImage.CData);
            imwrite(imgMirrored,fullDestination);
            
            msg = msgbox('Picture saved.','Success');
            pause(4)
            delete(msg)
            
        end

        % Value changed function: ResolutionSwitch
        function ResolutionSwitchValueChanged(app, event)
       
            % Change resize factor based on switch
            if app.ResolutionSwitch.Value == "High"
                app.WebcamResizeFactor = 1;
            elseif app.ResolutionSwitch.Value == "Low"
                app.WebcamResizeFactor = 1/2;      
            end
       
        end

        % Value changed function: StyleDropDown
        function StyleDropDownValueChanged(app, event)
            switch app.StyleDropDown.Value
               
                case 'No Style'  
                    
                    app.Image.ImageSource = "";
                    app.StyleImageNoStyleLabel.Text = "Style Image: No Style";
                    
                otherwise
                    
                    stylesDirectory = what('styles');
                    stylesPath = stylesDirectory.path;
                    imageFileName = fullfile(stylesPath,[app.StyleDropDown.Value,'.jpg']);
                    
                    if isfile(imageFileName)
                        app.StyleImageNoStyleLabel.Text = "Style Image";
                        app.Image.ImageSource = imageFileName;
                    else
                        app.Image.ImageSource = "";
                        app.StyleImageNoStyleLabel.Text=('Style Image: Not Found');
                    end
                    
            end
            
            % Store the index associated with the current dropdown value
            app.CurrentDropDownIndex = app.DropDownMap(app.StyleDropDown.Value);

            
        end

        % Button pushed function: ButtonLeft
        function ButtonLeftPushed(app, event)
            % Change dropdown value if left button pushed
            if app.CurrentDropDownIndex > 1
                app.CurrentDropDownIndex = app.CurrentDropDownIndex - 1;
            else
                app.CurrentDropDownIndex = length(app.StyleDropDown.Items);
            end
            app.StyleDropDown.Value = app.StyleDropDown.Items{app.CurrentDropDownIndex};
            app.StyleDropDownValueChanged()
        end

        % Button pushed function: ButtonRight
        function ButtonRightPushed(app, event)
            % Change dropdown value if right button pushed
            if app.CurrentDropDownIndex < length(app.StyleDropDown.Items)
                app.CurrentDropDownIndex = app.CurrentDropDownIndex + 1;
            else
                app.CurrentDropDownIndex = 1;
            end
            app.StyleDropDown.Value = app.StyleDropDown.Items{app.CurrentDropDownIndex};
            app.StyleDropDownValueChanged()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ArtisticStyleTransferLabel = uilabel(app.UIFigure);
            app.ArtisticStyleTransferLabel.Text = 'Artistic Style Transfer';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.StyleDropDown = uidropdown(app.GridLayout);
            app.StyleDropDown.Items = {'No Style', 'Wave'};
            app.StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @StyleDropDownValueChanged, true);
            app.StyleDropDown.Tooltip = {''; ''};
            app.StyleDropDown.Value = 'No Style';
            app.Image = uiimage(app.GridLayout);
            app.StyleImageNoStyleLabel = uilabel(app.GridLayout);
            app.StyleImageNoStyleLabel.Text = 'Style Image: No Style';
            app.TakePictureButton = uibutton(app.GridLayout, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Text = 'Take Picture';
            app.ButtonLeft = uibutton(app.GridLayout, 'push');
            app.ButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonLeftPushed, true);
            app.ButtonLeft.Text = {'<<'; ''};
            app.ButtonRight = uibutton(app.GridLayout, 'push');
            app.ButtonRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonRightPushed, true);
            app.ButtonRight.Text = {'>>'; ''};
            app.ResolutionSwitch = uiswitch(app.GridLayout, 'slider');
            app.ResolutionSwitch.Items = {'Low', 'High'};
            app.ResolutionSwitch.ValueChangedFcn = createCallbackFcn(app, @ResolutionSwitchValueChanged, true);
            app.ResolutionSwitch.Value = 'Low';
            app.ResolutionLabel = uilabel(app.GridLayout);
            app.ResolutionLabel.Text = 'Resolution';
            app.ProcessingUnit = uiswitch(app.GridLayout, 'slider');
            app.ProcessingUnit.Items = {'CPU', 'GPU'};
            app.ProcessingUnit.Value = 'GPU';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.XTick = [];
            app.UIAxes.YAxisLocation = 'right';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArtisticStyleTransfer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end